classdef AbbasCentral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdateDigraphCheckBox         matlab.ui.control.CheckBox
        WithinStateEditField          matlab.ui.control.EditField
        WithinStateEditFieldLabel     matlab.ui.control.Label
        RotationEndOfState            matlab.ui.control.ToggleSwitch
        SwitchLabel                   matlab.ui.control.Label
        UpperFreqLimitEditField       matlab.ui.control.NumericEditField
        UpperFreqLimitEditFieldLabel  matlab.ui.control.Label
        LowerFreqLimitEditField       matlab.ui.control.NumericEditField
        LowerFreqLimitEditFieldLabel  matlab.ui.control.Label
        PreviousTrialButton           matlab.ui.control.Button
        NextTrialButton               matlab.ui.control.Button
        BpodEventEditField            matlab.ui.control.EditField
        BpodEventEditFieldLabel       matlab.ui.control.Label
        OffsetsecondsEditField        matlab.ui.control.NumericEditField
        OffsetsecondsEditFieldLabel   matlab.ui.control.Label
        OutcomeDropDown               matlab.ui.control.DropDown
        OutcomeDropDownLabel          matlab.ui.control.Label
        PlotcurrentsettingsButton     matlab.ui.control.Button
        EdgessecondsEditField         matlab.ui.control.NumericEditField
        EdgessecondsEditFieldLabel    matlab.ui.control.Label
        PlotTypeButtonGroup           matlab.ui.container.ButtonGroup
        RotationButton                matlab.ui.control.RadioButton
        SpikePhaseButton              matlab.ui.control.RadioButton
        PerformanceButton             matlab.ui.control.RadioButton
        Power                         matlab.ui.control.RadioButton
        PSTH                          matlab.ui.control.RadioButton
        Raster                        matlab.ui.control.RadioButton
        LFPChannelButtonGroup         matlab.ui.container.ButtonGroup
        Chan32                        matlab.ui.control.ToggleButton
        Chan31                        matlab.ui.control.ToggleButton
        Chan30                        matlab.ui.control.ToggleButton
        Chan29                        matlab.ui.control.ToggleButton
        Chan28                        matlab.ui.control.ToggleButton
        Chan27                        matlab.ui.control.ToggleButton
        Chan26                        matlab.ui.control.ToggleButton
        Chan25                        matlab.ui.control.ToggleButton
        Chan24                        matlab.ui.control.ToggleButton
        Chan23                        matlab.ui.control.ToggleButton
        Chan22                        matlab.ui.control.ToggleButton
        Chan21                        matlab.ui.control.ToggleButton
        Chan20                        matlab.ui.control.ToggleButton
        Chan19                        matlab.ui.control.ToggleButton
        Chan18                        matlab.ui.control.ToggleButton
        Chan17                        matlab.ui.control.ToggleButton
        Chan16                        matlab.ui.control.ToggleButton
        Chan15                        matlab.ui.control.ToggleButton
        Chan14                        matlab.ui.control.ToggleButton
        Chan13                        matlab.ui.control.ToggleButton
        Chan12                        matlab.ui.control.ToggleButton
        Chan11                        matlab.ui.control.ToggleButton
        Chan10                        matlab.ui.control.ToggleButton
        Chan9                         matlab.ui.control.ToggleButton
        Chan8                         matlab.ui.control.ToggleButton
        Chan7                         matlab.ui.control.ToggleButton
        Chan6                         matlab.ui.control.ToggleButton
        Chan5                         matlab.ui.control.ToggleButton
        Chan4                         matlab.ui.control.ToggleButton
        Chan3                         matlab.ui.control.ToggleButton
        Chan2                         matlab.ui.control.ToggleButton
        Chan1                         matlab.ui.control.ToggleButton
        CWTPowerButton                matlab.ui.control.Button
        TrialTypesListBox             matlab.ui.control.ListBox
        TrialTypesListBoxLabel        matlab.ui.control.Label
        AlignmentDropDown             matlab.ui.control.DropDown
        AlignmentDropDownLabel        matlab.ui.control.Label
        DigraphPanel                  matlab.ui.container.Panel
        ConfirmSelectionButton        matlab.ui.control.Button
        PlotsPanel                    matlab.ui.container.Panel
        NeuronDropDown                matlab.ui.control.DropDown
        NeuronDropDownLabel           matlab.ui.control.Label
        SessionDropDown               matlab.ui.control.DropDown
        SessionDropDownLabel          matlab.ui.control.Label
        SubjectDropDown               matlab.ui.control.DropDown
        SubjectDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        loadSelection   % Used in startup fcn to load workspace variable
        sessions        % all sessions in the current dataset
        metadata        % metadata from select_experiment
        currentObject   % object loaded into app
        currentSession  % name of the current session
        updateDigraph   % bool to update digraph for each change in params
        currentChannel  % LFP channel looked at
        neuronNo        % Neuron number currently selected
        alignment       % Task alignment currently selected
        bpodEvent       % Boolean to determine if functions should use find_event or find_bpod_event
        bpodEventName   % Name in bpod field        
        withinState     % States to search for bpod event within (char/cell)
        edges           % Distance backward and forward from alignment (sec)
        power           % Structure that holds calculated LFP data. Subfield layout: session -> alignment -> trialType -> outcome
        freqs           % Frequency labels for power plots
        trialTypes      % Currently selected trial types to examine
        outcomes        % Currently selected outcomes to examine
        offset          % Offset backward (negative) or forward (positive) from alignment
        freqLimits      % Frequency limits for bandpass function
        currentTrial    % Currently selected trial
        maxTrials       % Total number of bpod trials
        filteredSignal  % Stored filtered signal for one session
        rotEOS          % Toggled switch for rotation plotter

    end
    
    methods (Access = private)
        
        
        function update_digraph(app)
            d = uiprogressdlg(app.UIFigure, 'Title', "Updating Directed Graph", 'Indeterminate', 'on');
            drawnow
            if ~app.updateDigraph || isempty(app.alignment) || strcmp(app.PlotTypeButtonGroup.SelectedObject.Text, "Rotation")
                close(d)
                return
            end
            delete(app.DigraphPanel.Children)
            app.currentObject.plot_digraph(app.currentObject.trialize_mono_excitatory(app.alignment, 'trialType', app.trialTypes, 'outcome', app.outcomes, ...
                'edges', [-app.edges app.edges], 'offset', app.offset, 'bpod', app.bpodEvent), app.DigraphPanel);
            close(d)
%             app.DigraphPanel.Children.Properties
            if isfield(app.DigraphPanel.Children, 'Children')
                app.DigraphPanel.Children.Children(1).ButtonDownFcn = @app.scatterClickFcn;
            end
        end
        
        function plot_current_type(app)
            if isempty(app.alignment)
                return
            end
            delete(app.PlotsPanel.Children)
            app.RotationEndOfState.Visible = 'off';
            plotPanelSelection = app.PlotTypeButtonGroup.SelectedObject.Text;
            plotPanelOptions = {'Raster', 'PSTH', 'Power', 'Performance', 'Spike Phase', 'Rotation'};
            plotPanelFcns =  {@raster_fcn, @psth_fcn, @pwr_fcn, @perf_fcn, @spike_phase_fcn, @rot_fcn};
            fcnMap = containers.Map(plotPanelOptions, plotPanelFcns);
            plotFcn = fcnMap(plotPanelSelection);
            plotFcn(app);
        end

        function raster_fcn(app)
            if strcmp("Select a neuron", string(app.NeuronDropDown.Value))
                return
            end
            if app.neuronNo <= numel(app.currentObject.spikes)
                app.currentObject.raster(app.alignment, app.neuronNo, 'trialType', app.trialTypes, 'outcome', app.outcomes, ...
                    'panel', app.PlotsPanel, 'edges', [-app.edges app.edges], 'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState);
            end
        end

        function psth_fcn(app)
            if strcmp("Select a neuron", string(app.NeuronDropDown.Value))
                return
            end
            if app.neuronNo <= numel(app.currentObject.spikes)
                app.currentObject.psth(app.alignment, app.neuronNo, 'trialType', app.trialTypes, 'outcome', app.outcomes, ...
                    'panel', app.PlotsPanel, 'edges', [-app.edges app.edges], 'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState);
            end
        end

        function pwr_fcn(app)
            sessionName = regexprep(app.currentSession, '[()-]', '_');
            if isempty(app.trialTypes)
                trialTypesStruct = 'All';
            else
                trialTypesStruct = app.trialTypes;
            end
            if isempty(app.outcomes)
                outcomesStruct = 'All';
            else
                outcomesStruct = app.outcomes;
            end
            if ~is_subfield(app.power.(sessionName), sprintf("%s.%s.%s", app.alignment, trialTypesStruct, outcomesStruct))
                return
            end            
            plot_cwt(app.power.(sessionName).(app.alignment).(trialTypesStruct).(outcomesStruct), app.currentChannel, app.freqs, app.PlotsPanel);
        end

        function perf_fcn(app)
            app.currentObject.plot_performance(0, app.PlotsPanel)
        end

        function spike_phase_fcn(app)
            app.LFPChannelButtonGroup.Visible = 'on';
            app.PlotsPanel.AutoResizeChildren = 'off';
    %                     ax1 = subplot(2, 1, 1, 'Parent', app.PlotsPanel);
            allSpikes = app.currentObject.bin_neuron(app.alignment, app.neuronNo, 'trialType', app.trialTypes, 'outcome', app.outcomes, ...
                'edges', [-app.edges app.edges], 'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState);
            binnedTrial = [zeros(1, size(allSpikes, 2)); allSpikes(app.currentTrial, :)];
            f = figure('Visible', 'off');
            t = tiledlayout(f, 1, 1, 'Visible', 'off'); 
            ax1 = axes(t);
    %                     g = figure('Visible', 'off');
    %                     test = subplot(2, 1, 1, 'Parent', g);
            plotSpikeRaster(boolean(binnedTrial), 'PlotType', 'vertline', 'VertSpikeHeight', 2.5);
    %                     copyobj(g.Children, app.PlotsPanel)
    %                     close(g)
    %                     ax2 = subplot(2, 1, 2, 'Parent', app.PlotsPanel);
            if isempty(app.filteredSignal)
                d = uiprogressdlg(app.UIFigure, 'Title', "Filtering signal...", 'Indeterminate', 'on');
                drawnow
                app.filteredSignal = app.currentObject.filter_signal(app.alignment, 'freqLimits', app.freqLimits, ...
                    'trialType', app.trialTypes, 'outcome', app.outcomes, 'edges', [-app.edges app.edges], ...
                    'offset', app.offset, 'bpod', app.bpodEvent, 'withinState', app.withinState, 'filter', 'butter');
                close(d)
            end
            ax2 = axes(t);
            plot(ax2, app.filteredSignal{app.currentTrial}(:, app.currentChannel))
            ax2.XAxisLocation = 'top';
            ax2.YAxisLocation = 'right';
            set(gca, 'Xcolor', 'w', 'Ycolor', 'w')
            set(f, 'Color', 'k')
            set(gca, 'XTick', [])
            set(gca, 'YTick', [])
            ax2.Color = 'none';
            ax1.Box = 'off';
            ax2.Box = 'off';
            title(strcat("Current trial: ", num2str(app.currentTrial)))
            copyobj(t.Children, app.PlotsPanel)
            close(f)
        end

        function rot_fcn(app)
            app.RotationEndOfState.Visible = 'on';
            selectedAngles = app.currentObject.trialize_rotation(app.alignment, 'edges', [-app.offset, app.edges], 'trialType', app.trialTypes, ...
                'outcome', app.outcomes, 'eos', app.rotEOS);
            rotation_surf(selectedAngles, app.PlotsPanel)
        end
        
        function reset_power_struct(app)
            app.power = struct();
            sessionName = regexprep(app.currentSession, '[()-]', '_');
            app.power.(sessionName) = struct();       
        end

        function show_button_group(app)
            sessionName = regexprep(app.currentSession, '[()-]', '_');
            if isempty(app.trialTypes)
                trialTypesStruct = 'All';
            else
                trialTypesStruct = app.trialTypes;
            end
            if isempty(app.outcomes)
                outcomesStruct = 'All';
            else
                outcomesStruct = app.outcomes;
            end
            if ~is_subfield(app.power.(sessionName), sprintf("%s.%s.%s", app.alignment, trialTypesStruct, outcomesStruct))
                app.LFPChannelButtonGroup.Visible = 'off';
            else
                app.LFPChannelButtonGroup.Visible = 'on';
            end
        end

        function neuronSelected = scatterClickFcn(app, hobj, event)
            %  FIND NEAREST (X,Y,Z) COORDINATE TO MOUSE CLICK
            % Inputs:
            %  hObj: the axes
            %  event: info about mouse click
            % OUTPUT
            %  coordinateSelected: the (x,y) coordinate you selected
            %  minIDx: The index of your inputs that match coordinateSelected. 
            x = hobj.XData;
            y = hobj.YData;
            pt = event.IntersectionPoint(1:2);      % The (x0,y0) coordinate you just selected
            coordinates = [x(:),y(:)];              % matrix of your input coordinates
            dist = pdist2(pt,coordinates);          % distance between your selection and all points
            [~, neuronSelected] = min(dist);        % index of minimum distance to points
            app.neuronNo = neuronSelected;
            app.NeuronDropDown.Value = num2str(neuronSelected);
            app.plot_current_type;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Uncomment to continue work on the uitable variable selector menu
            app.loadSelection = 0;
            app.withinState=[];
            % Before calling the whos function from evalin, we'll do a 
            % security check that the whos function is the one provided
            % by Matlab.  This reduces the risk of using evalin().
            if isempty(regexp(which('whos'), 'built-in *.'))
                error('The built-in whos() function is being overshaddowed.  Do not proceed.')
            end
            baseVarInfo = evalin('base', 'whos');
            matches =  strcmp({baseVarInfo.class}, 'ExpManager');
            goodVars = {baseVarInfo(matches).name};
            baseVarInfo = rmfield(baseVarInfo,{'nesting','global','size','sparse','complex','persistent'});
            baseVarCell = [num2cell(false(numel(goodVars), 1)),struct2cell(baseVarInfo(matches))']; % selection column
            varNames = [{'Select'}; fieldnames(baseVarInfo)];
            goodVarTable = cell2table(baseVarCell, 'RowNames', goodVars, 'VariableNames', varNames);
            % Get base workspace variable inf
            fig = uifigure('position', [420 420 550, 350], 'Visible', 'off');
            uit = uitable(fig, 'position', [20 20 500, 300],...
                'ColumnEditable', [true, false(1,1)], 'Tag', 'BaseWorkspaceVarInfo');
            uit.Data = goodVarTable; 
            copyobj(uit, app.DigraphPanel)   
            close(fig)
            appTable = app.DigraphPanel.Children(1);
            % Find the handle to the UITable (note the tag name assigned to the UITable)
            % This step is avoidable if the UITable handle is already stored in app.
            uitab = findall(appTable, 'Tag', 'BaseWorkspaceVarInfo'); 
            % Get the user's selection and extract the variable names (varsToLoad).

            drawnow
            waitfor(app.ConfirmSelectionButton, 'UserData', true);

            selection = uitab.Data.Select; 
            varsToLoad = uitab.RowName(selection); 
            % loop through each variable and store it's values in a cell array. 
            varValues = cell(size(varsToLoad)); 
            for i = 1:numel(varsToLoad)
                varValues{i} = evalin('base', varsToLoad{i}); 
            end

            app.sessions = varValues{1}.sessions;
            app.metadata = varValues{1}.metadata;
            s = app.metadata.subjects;
            s = [{'Select a subject'}, s];
            app.SubjectDropDown.Items = s;
            app.updateDigraph = true;
            app.edges = 2;
            app.offset = 0;
            app.power = struct();
            app.LFPChannelButtonGroup.Visible = 'off';
            app.trialTypes = [];
            app.outcomes = [];
            app.alignment = [];
            app.bpodEvent = false;
            app.BpodEventEditField.Visible = 'off';
            app.filteredSignal = [];
            app.currentChannel = 1;
            app.neuronNo = [];
            app.RotationEndOfState.Visible = 'off';
            app.rotEOS = false;
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)

            value = app.SubjectDropDown.Value;
            if ~strcmp(string(value), "Select a subject")
                subSessions = arrayfun(@(x) strcmp(x.info.name, value), app.sessions);
                [x{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
                sessionNames = x{2}(subSessions);
%                 sessionNames = cellfun(@(x) regexprep(x, '[()-]', '_'), sessionNames, 'uni', 0);
                sessionNames = [{'Select a session'}, sessionNames];
                app.SessionDropDown.Items = sessionNames;
                app.LFPChannelButtonGroup.Visible = 'off';
            end
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            sessionName = regexprep(value, '[()-]', '_');
            if ~strcmp(string(value), "Select a session")
                [I{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
                whichSession = arrayfun(@(x) strcmp(x, value), I{2});
                app.currentObject = app.sessions(whichSession);
                delete(app.DigraphPanel.Children)
                app.currentObject.plot_digraph([], app.DigraphPanel);
                % This ugly line of code ensures you can click the scatter
                % plot to select a neuron - the Child of DigraphPanel is
                % the axes, whose first child is the scatter plot.
                if isfield(app.DigraphPanel, 'Children')
                    app.DigraphPanel.Children.Children(1).ButtonDownFcn = @app.scatterClickFcn;
                end
                neuronString = arrayfun(@num2str, 1:numel(app.currentObject.spikes), 'uni', 0);
                neuronString = [{'Select a neuron'}, neuronString];
                app.NeuronDropDown.Items = neuronString;
                alignments = fields(app.currentObject.timestamps.keys)';
                appAlignments = [{'Select an alignment', 'Bpod'}, alignments];
                app.AlignmentDropDown.Items = appAlignments;
                availableTT = fields(app.currentObject.info.trialTypes)';
                availableTT = [{'All'}, availableTT];
                app.TrialTypesListBox.Items = availableTT;
                availableOutcomes = fields(app.currentObject.info.outcomes)';
                availableOutcomes = [{'All'}, availableOutcomes];
                app.OutcomeDropDown.Items = availableOutcomes;
                app.LowerFreqLimitEditField.Value = 6;
                app.UpperFreqLimitEditField.Value = 12;
                app.freqLimits = [6 12];
                app.filteredSignal = [];
                % Structure subfield layout: session -> alignment -> trialType -> outcome

                if ~isfield(app.power, sessionName)
                    app.power.(sessionName) = struct();       
                end
                app.currentSession = value;
                app.currentTrial = 1;
                app.maxTrials = app.currentObject.bpod.nTrials;
                app.show_button_group
                app.plot_current_type
                app.update_digraph
            end
        end

        % Value changed function: NeuronDropDown
        function NeuronDropDownValueChanged(app, event)
            value = app.NeuronDropDown.Value;
            if ~strcmp(string(value), "Select a neuron")
                if ~isempty(app.neuronNo)                
                    try
                    eval(strcat("app.Chan", num2str(app.currentObject.spikes(app.neuronNo).channel), ".BackgroundColor = [.96 .96 .96];"))
                    catch
                    app.Chan1.BackgroundColor = [.96 .96 .96];
                    end
                end
                app.neuronNo = str2double(app.NeuronDropDown.Value);
                try
                    spikeChannel = app.currentObject.spikes(app.neuronNo).channel;
                catch
                    warning('No channel region found. Automatically putting 1 for quickndirty')
                    spikeChannel = 1;
                end
                eval(strcat("app.Chan", num2str(spikeChannel), ".BackgroundColor = 'g';"));
            end
            app.plot_current_type
        end

        % Value changed function: AlignmentDropDown
        function AlignmentDropDownValueChanged(app, event)
            value = app.AlignmentDropDown.Value;
            app.filteredSignal = [];
            if ~ismember(value, {'Select an alignment', 'Bpod'})
                app.BpodEventEditField.Visible = 'off';
                app.WithinStateEditField.Visible = 'off';
                app.bpodEvent = false;
                app.alignment = value;
                app.show_button_group
                app.maxTrials = numel(app.currentObject.find_event(app.alignment, 'trialType', app.trialTypes, ...
                    'outcome', app.outcomes, 'offset', app.offset));
                app.plot_current_type
                app.update_digraph
            end
            if strcmp(string(value), "Bpod")
                app.bpodEvent = true;
                app.alignment = app.BpodEventEditField.Value;
                app.BpodEventEditField.Visible = 'on';
                app.WithinStateEditField.Visible = 'on';
                app.maxTrials = numel(app.currentObject.find_bpod_event(app.alignment, 'trialType', app.trialTypes, ...
                    'outcome', app.outcomes, 'offset', app.offset));
                app.plot_current_type
                app.update_digraph
            end
        end

        % Value changed function: BpodEventEditField
        function BpodEventEditFieldValueChanged(app, event)
            
            value = app.BpodEventEditField.Value;
            app.filteredSignal = [];
            app.alignment = value;
            app.bpodEventName = value;
            app.maxTrials = numel(app.currentObject.find_bpod_event(app.alignment, 'trialType', app.trialTypes, ...
                    'outcome', app.outcomes, 'offset', app.offset));
            app.show_button_group
            app.plot_current_type
            app.update_digraph
        end

        % Value changed function: WithinStateEditField
        function WithinStateEditFieldValueChanged(app, event)
            app.withinState = app.WithinStateEditField.Value;
            app.show_button_group
            app.plot_current_type
            app.update_digraph
            
        end

        % Button pushed function: CWTPowerButton
        function CWTPowerButtonPushed(app, event)
            sessionName = regexprep(app.currentSession, '[()-]', '_');

            % Structure subfield layout: session -> alignment -> trialType -> outcome
            if isempty(app.trialTypes)
                trialTypesStruct = 'All';
            else
                trialTypesStruct = app.trialTypes;
            end
            if isempty(app.outcomes)
                outcomesStruct = 'All';
            else
                outcomesStruct = app.outcomes;
            end
            if ~is_subfield(app.power.(sessionName), sprintf("%s.%s.%s", app.alignment, trialTypesStruct, outcomesStruct))
                d = uiprogressdlg(app.UIFigure, 'Title', "Calculating CWT", 'Indeterminate', 'on');
                drawnow
                [pwr, app.freqs] = app.currentObject.cwt_power(app.alignment, 'edges', [-app.edges app.edges], 'calculatePhase', false, ...
                    'bpod', app.bpodEvent, 'withinState', app.withinState, 'offset', app.offset, 'trialType', app.trialTypes, 'outcome', app.outcomes);
                app.power.(sessionName).(app.alignment).(trialTypesStruct).(outcomesStruct) = cellfun(@(x) mean(x, 3), pwr, 'uni', 0);
                close(d)
                app.LFPChannelButtonGroup.Visible = 'on';
                app.currentChannel = 1;
            end
            app.plot_current_type
        end

        % Selection changed function: LFPChannelButtonGroup
        function LFPChannelButtonGroupSelectionChanged(app, event)
            selectedButton = app.LFPChannelButtonGroup.SelectedObject;
            app.currentChannel = str2double(selectedButton.Text);
            delete(app.PlotsPanel.Children)
%             app.PlotTypeButtonGroup.SelectedObject = app.Power;
            app.plot_current_type
        

        end

        % Value changed function: TrialTypesListBox
        function TrialTypesListBoxValueChanged(app, event)
            value = app.TrialTypesListBox.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.trialTypes))
                app.trialTypes = value;
            else
                app.trialTypes = [];
            end                
            app.show_button_group
            app.plot_current_type
            app.update_digraph

        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            app.plot_current_type
        end

        % Value changed function: EdgessecondsEditField
        function EdgessecondsEditFieldValueChanged(app, event)
            app.edges = app.EdgessecondsEditField.Value;
            app.filteredSignal = [];
            app.LFPChannelButtonGroup.Visible = 'off';
            app.reset_power_struct
            app.plot_current_type
            app.update_digraph
            
        end

        % Button pushed function: PlotcurrentsettingsButton
        function PlotcurrentsettingsButtonPushed(app, event)
            app.plot_current_type
        end

        % Value changed function: OutcomeDropDown
        function OutcomeDropDownValueChanged(app, event)
            value = app.OutcomeDropDown.Value;
            app.filteredSignal = [];
            if ismember(value, fields(app.currentObject.info.outcomes))
                app.outcomes = value;
            else
                app.outcomes = [];
            end
            app.show_button_group
            app.plot_current_type
            app.update_digraph
        end

        % Value changed function: OffsetsecondsEditField
        function OffsetsecondsEditFieldValueChanged(app, event)
            app.offset = app.OffsetsecondsEditField.Value;
            app.filteredSignal = [];
            app.LFPChannelButtonGroup.Visible = 'off';
            app.reset_power_struct   
            app.plot_current_type
            app.update_digraph
        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)
            app.currentTrial = app.currentTrial - 1;
            if app.currentTrial == 0
                app.currentTrial = app.maxTrials;
            end
            d = uiprogressdlg(app.UIFigure, 'Title', "Changing trials, Randall...", 'Indeterminate', 'on');
            drawnow
            app.plot_current_type
            close(d)
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            app.currentTrial = app.currentTrial + 1;
            if app.currentTrial > app.maxTrials
                app.currentTrial = 1;
            end

            d = uiprogressdlg(app.UIFigure, 'Title', "Changing trials, Randall...", 'Indeterminate', 'on');
            drawnow
            app.plot_current_type
            close(d)
        end

        % Value changed function: LowerFreqLimitEditField
        function LowerFreqLimitEditFieldValueChanged(app, event)
            app.freqLimits(1) = app.LowerFreqLimitEditField.Value;
            app.filteredSignal = [];
        end

        % Value changed function: UpperFreqLimitEditField
        function UpperFreqLimitEditFieldValueChanged(app, event)
            app.freqLimits(2) = app.UpperFreqLimitEditField.Value;
            app.filteredSignal = [];
        end

        % Value changed function: RotationEndOfState
        function RotationEndOfStateValueChanged(app, event)
            eos = app.RotationEndOfState.Value;
            if strcmp(eos, 'Off')
                app.rotEOS = false;
            else
                app.rotEOS = true;
            end
            app.plot_current_type
        end

        % Value changed function: UpdateDigraphCheckBox
        function UpdateDigraphCheckBoxValueChanged(app, event)
            app.updateDigraph = app.UpdateDigraphCheckBox.Value;
            
        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)
            app.loadSelection = 1;
            app.ConfirmSelectionButton.UserData = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.NeuronDropDownLabel = uilabel(app.UIFigure);
            app.NeuronDropDownLabel.Text = 'Neuron';
            app.NeuronDropDown = uidropdown(app.UIFigure);
            app.NeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuronDropDownValueChanged, true);
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.DigraphPanel = uipanel(app.UIFigure);
            app.DigraphPanel.Title = 'Digraph';
            app.ConfirmSelectionButton = uibutton(app.DigraphPanel, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = 'Confirm Selection';
            app.AlignmentDropDownLabel = uilabel(app.UIFigure);
            app.AlignmentDropDownLabel.Text = 'Alignment';
            app.AlignmentDropDown = uidropdown(app.UIFigure);
            app.AlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmentDropDownValueChanged, true);
            app.TrialTypesListBoxLabel = uilabel(app.UIFigure);
            app.TrialTypesListBoxLabel.Text = 'TrialTypes';
            app.TrialTypesListBox = uilistbox(app.UIFigure);
            app.TrialTypesListBox.ValueChangedFcn = createCallbackFcn(app, @TrialTypesListBoxValueChanged, true);
            app.CWTPowerButton = uibutton(app.UIFigure, 'push');
            app.CWTPowerButton.ButtonPushedFcn = createCallbackFcn(app, @CWTPowerButtonPushed, true);
            app.CWTPowerButton.Text = 'CWT Power';
            app.LFPChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.LFPChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LFPChannelButtonGroupSelectionChanged, true);
            app.LFPChannelButtonGroup.Title = 'LFP Channel';
            app.Chan1 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan1.Text = '1';
            app.Chan1.Value = true;
            app.Chan2 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan2.Text = '2';
            app.Chan3 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan3.Text = '3';
            app.Chan4 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan4.Text = '4';
            app.Chan5 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan5.Text = '5';
            app.Chan6 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan6.Text = '6';
            app.Chan7 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan7.Text = '7';
            app.Chan8 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan8.Text = '8';
            app.Chan9 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan9.Text = '9';
            app.Chan10 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan10.Text = '10';
            app.Chan11 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan11.Text = '11';
            app.Chan12 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan12.Text = '12';
            app.Chan13 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan13.Text = '13';
            app.Chan14 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan14.Text = '14';
            app.Chan15 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan15.Text = '15';
            app.Chan16 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan16.Text = '16';
            app.Chan17 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan17.Text = '17';
            app.Chan18 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan18.Text = '18';
            app.Chan19 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan19.Text = '19';
            app.Chan20 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan20.Text = '20';
            app.Chan21 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan21.Text = '21';
            app.Chan22 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan22.Text = '22';
            app.Chan23 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan23.Text = '23';
            app.Chan24 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan24.Text = '24';
            app.Chan25 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan25.Text = '25';
            app.Chan26 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan26.Text = '26';
            app.Chan27 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan27.Text = '27';
            app.Chan28 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan28.Text = '28';
            app.Chan29 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan29.Text = '29';
            app.Chan30 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan30.Text = '30';
            app.Chan31 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan31.Text = '31';
            app.Chan32 = uitogglebutton(app.LFPChannelButtonGroup);
            app.Chan32.Text = '32';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.Raster = uiradiobutton(app.PlotTypeButtonGroup);
            app.Raster.Text = 'Raster';
            app.Raster.Value = true;
            app.PSTH = uiradiobutton(app.PlotTypeButtonGroup);
            app.PSTH.Text = 'PSTH';
            app.Power = uiradiobutton(app.PlotTypeButtonGroup);
            app.Power.Text = 'Power';
            app.PerformanceButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.PerformanceButton.Text = 'Performance';
            app.SpikePhaseButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.SpikePhaseButton.Text = 'Spike Phase';
            app.RotationButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RotationButton.Text = 'Rotation';
            app.EdgessecondsEditFieldLabel = uilabel(app.UIFigure);
            app.EdgessecondsEditFieldLabel.Text = 'Edges (seconds)';
            app.EdgessecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgessecondsEditField.ValueChangedFcn = createCallbackFcn(app, @EdgessecondsEditFieldValueChanged, true);
            app.PlotcurrentsettingsButton = uibutton(app.UIFigure, 'push');
            app.PlotcurrentsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcurrentsettingsButtonPushed, true);
            app.PlotcurrentsettingsButton.Text = 'Plot current settings';
            app.OutcomeDropDownLabel = uilabel(app.UIFigure);
            app.OutcomeDropDownLabel.Text = 'Outcome';
            app.OutcomeDropDown = uidropdown(app.UIFigure);
            app.OutcomeDropDown.ValueChangedFcn = createCallbackFcn(app, @OutcomeDropDownValueChanged, true);
            app.OffsetsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetsecondsEditFieldLabel.Text = 'Offset (seconds)';
            app.OffsetsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetsecondsEditFieldValueChanged, true);
            app.BpodEventEditFieldLabel = uilabel(app.UIFigure);
            app.BpodEventEditFieldLabel.Text = 'Bpod Event';
            app.BpodEventEditField = uieditfield(app.UIFigure, 'text');
            app.BpodEventEditField.ValueChangedFcn = createCallbackFcn(app, @BpodEventEditFieldValueChanged, true);
            app.NextTrialButton = uibutton(app.UIFigure, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = 'Next Trial';
            app.PreviousTrialButton = uibutton(app.UIFigure, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Text = 'Previous Trial';
            app.LowerFreqLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerFreqLimitEditFieldLabel.Text = 'Lower Freq Limit';
            app.LowerFreqLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerFreqLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFreqLimitEditFieldValueChanged, true);
            app.UpperFreqLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperFreqLimitEditFieldLabel.Text = 'Upper Freq Limit';
            app.UpperFreqLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperFreqLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFreqLimitEditFieldValueChanged, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.RotationEndOfState = uiswitch(app.UIFigure, 'toggle');
            app.RotationEndOfState.ValueChangedFcn = createCallbackFcn(app, @RotationEndOfStateValueChanged, true);
            app.WithinStateEditFieldLabel = uilabel(app.UIFigure);
            app.WithinStateEditFieldLabel.Text = 'Within State';
            app.WithinStateEditField = uieditfield(app.UIFigure, 'text');
            app.WithinStateEditField.ValueChangedFcn = createCallbackFcn(app, @WithinStateEditFieldValueChanged, true);
            app.UpdateDigraphCheckBox = uicheckbox(app.UIFigure);
            app.UpdateDigraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDigraphCheckBoxValueChanged, true);
            app.UpdateDigraphCheckBox.Text = 'Update Digraph';
            app.UpdateDigraphCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbbasCentral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end